classdef GUIversuchzwei < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StartButton          matlab.ui.control.Button
        StopButton           matlab.ui.control.Button
        ScanButton           matlab.ui.control.Button
        ClumsyGoldfishLabel  matlab.ui.control.Label
        IstStatusLabel       matlab.ui.control.Label
        SollStatusLabel      matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
        UIAxes               matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        vRob=VirtualRobot('random'); % Start des vRob "random"
    end
    
    properties (Access = public)
        A=[-1 -1;-1 -1;-1 -1;-1 -1;-1 -1;-1 -1;-1 -1;-1 -1;-1 -1]; % Anfangsmatrix
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global A
            tauschvorgang(A,app.vRob)
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            global A
            A=[-1 1;-1 1;-1 1;-1 1;-1 1;-1 1;-1 1;-1 1;-1 1]
            A = scanAll(A,app.vRob)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = {'Stop'; ''};
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = {'Scan'; ''};
            app.ClumsyGoldfishLabel = uilabel(app.UIFigure);
            app.ClumsyGoldfishLabel.Text = 'ClumsyGoldfish';
            app.IstStatusLabel = uilabel(app.UIFigure);
            app.IstStatusLabel.Text = 'Ist-Status';
            app.SollStatusLabel = uilabel(app.UIFigure);
            app.SollStatusLabel.Text = 'Soll-Status';
            app.TextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIversuchzwei

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end